classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InputParametersUIFigure  matlab.ui.Figure
        Button                   matlab.ui.control.Button
        Label                    matlab.ui.control.Label
        EditField                matlab.ui.control.NumericEditField
        Label_2                  matlab.ui.control.Label
        EditField_2              matlab.ui.control.NumericEditField
        Button_2                 matlab.ui.control.Button
        Label_3                  matlab.ui.control.Label
        Button_3                 matlab.ui.control.Button
        Label_4                  matlab.ui.control.Label
        EditField_3              matlab.ui.control.NumericEditField
        Label_5                  matlab.ui.control.Label
        EditField_4              matlab.ui.control.NumericEditField
        Label_6                  matlab.ui.control.Label
        DropDown                 matlab.ui.control.DropDown
    end

    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Инициализируем глобальные переменные
            global img;
            global edgeImg;
            % save будет принимать значение 1, когда обработка будет произведена успешно
            global save;
            % Процесс обработки изображения начнется, если переменная img не будет пуста
            if (~isnan(img))
                if(ndims(img) == 3)
                    % Преобразование изображения в оттенки серого если оно цветное
                    grayImg = rgb2gray(img);
                else
                    % Переменная grayImg есть img, если исходная картинка полутоновая
                    grayImg = img;
                end
                % Увеличение размера изображения для более точной обработки
                if size(grayImg) < 2000
                    % Увеличение изображения в 2 раза, если оно меньше 2000 пикселей
                    grayImg = imresize(grayImg, 2);
                end
                % Переменные для регулировки границ, вводимые пользователем
                gau = app.EditField.Value;
                tr = app.EditField_2.Value;
                nm = app.EditField_3.Value;
                ed = app.EditField_4.Value;
                del = round(nm * 6);
                % Сглаживание изображения фильтром Гаусса
                smoothImg = imgaussfilt(grayImg, gau);
                if(app.DropDown.Value == "Собеля")
                    % Операторы Собеля для вычисления градиентов
                    sobelVertical = [-1, -2, -1; 0, 0, 0; 1, 2, 1];
                    sobelHorizontal = [-1, 0, 1; -2, 0, 2; -1, 0, 1];
                    % Применение операторов Собеля к изображению
                    gradX = conv2(double(smoothImg), sobelHorizontal, 'same');
                    gradY = conv2(double(smoothImg), sobelVertical, 'same');
                    % Вычисление общего градиента и направления
                    gradMag = sqrt(gradX.^2 + gradY.^2);
                elseif (app.DropDown.Value == "Робертса")
                    % Операторы Робертса для вычисления градиентов
                    h_roberts_x = [1, 0; 0, -1];
                    h_roberts_y = [0, 1; -1, 0];
                    % Применение операторов Робертса к изображению
                    grad_x = conv2(double(smoothImg), h_roberts_x, 'same');
                    grad_y = conv2(double(smoothImg), h_roberts_y, 'same');
                    % Вычисление общего градиента
                    gradMag = sqrt(grad_x.^2 + grad_y.^2);
                    % Выравнивание параметров предотвращения повторной настройки
                elseif(app.DropDown.Value == "Превитта")
                    % Операторы Превитта для вычисления градиентов
                    PrewX = [-1, -1, -1; 0, 0, 0; 1, 1, 1];
                    PrewY = [-1, 0, 1; -1, 0, 1; -1, 0, 1];
                    % Применение операторов Превитта к изображению
                    gradX = conv2(double(smoothImg), PrewX, 'same');
                    gradY = conv2(double(smoothImg), PrewY, 'same');
                    % Вычисление общего градиента и направления
                    gradMag = sqrt(gradX.^2 + gradY.^2);
                elseif (app.DropDown.Value == "Шарра")
                    % Определение операторов Шарра
                    sharX = [-3 0 3; -10 0 10; -3 0 3];
                    sharY = [-3 -10 -3; 0 0 0; 3 10 3];
                    % Применение операторов Шарра
                    grad_x = conv2(double(smoothImg), sharX, 'same');
                    grad_y = conv2(double(smoothImg), sharY, 'same');
                    % Вычисление градиента магнитуды
                    gradMag = sqrt(grad_x.^2 + grad_y.^2);
                elseif (app.DropDown.Value == "Кирша")
                    % Определение операторов Кирша для 8 направлений
                    KirschMasks = {
                        [5 5 5; -3 0 -3; -3 -3 -3]
                        [-3 5 5; -3 0 5; -3 -3 -3]
                        [-3 -3 5; -3 0 5; -3 -3 5]
                        [-3 -3 -3; -3 0 5; -3 5 5]
                        [-3 -3 -3; -3 0 -3; 5 5 5]
                        [-3 -3 -3; 5 0 -3; 5 5 -3]
                        [5 -3 -3; 5 0 -3; 5 -3 -3]
                        [5 5 -3; 5 0 -3; -3 -3 -3]
                    };
                    gradSum = 0;
                    for i = 1:length(KirschMasks)
                        % Применение операторов Кирша
                        grad = conv2(double(smoothImg), KirschMasks{i}, 'same');
                        gradSum = grad.^2+gradSum;
                    end
                    % Градиент магнитуды
                    gradMag = sqrt(gradSum);
                end
                % Нормализация значений яркости
                gradMag = ((gradMag / max(gradMag(:))) * 255);
                % Подавление немаксимумов
                nmsImg = imhmax(gradMag, 3*nm);
                % Применение порога для регулировки границ
                tresh = 0.1/tr * 255;
                ThreshImg = nmsImg >= tresh;
                % Утончение границ
                ThinImg = bwmorph(ThreshImg, 'thin', inf);
                % Удаление изолированных пикселей
                DenoisedImg = bwareaopen(ThinImg, del);
                % Утолщение границ для лучшего визуального восприятия
                edgeImg = imdilate(DenoisedImg, strel('disk', ed));
                % Вывод результатов
                subplot(1,2,1), imshow(img), title('Исходное изображение');
                subplot(1,2,2), imshow(edgeImg), title('Результат выделения границ');
                app.Label_3.Text = ('Готово!');
                % Переменную save приравниваем к 1, так как процесс обработки прошел успешно
                save = 1;
            else
                % Если переменная img будет пуста (когда не выбран файл)
                app.Label_3.Text = ('Сначала выберите изображение');
                % Приравниваем save к нулю, так как нечего обрабатывать
                save = 0;
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            global img;
            global save;
            % Переменная ext возвращает значение индекса матрицы разрешенных форматов
            [filename, pathname, ext] = uigetfile({'*.jpg';'*.png';'*.jpeg'}, 'Выберите изображение');
            % Если выбор изображения был отменен
            if isequal(filename,0) || isequal(pathname,0)
                app.Label_3.Text = ('Изображение не выбрано. Повторите попытку');
                % Разыменование img, поскольку файл не выбран
                img = nan;
                % save приравниваем к 0, так как файл не был выбран
                save = 0;
                return;
            end
            % Переменная ext должна вернуть индекс элемента из матрицы разрешенных форматов
            if (ext == 1 || ext == 2 || ext == 3)
                img = imread(fullfile(pathname, filename));
                app.Label_3.Text = ('Теперь нажмите "Выделить границы"');
                return;
            else
                % При выборе неразрешенного формата ext вернет другое значение
                app.Label_3.Text = ('Выбран неверный тип файла');
                % Разыменование img, поскольку выбран неверный формат
                img = nan;
                % save приравниваем к 0, так как был выбран неверный формат
                save = 0;
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            global save;
            global edgeImg;
            % Если save будет равен 1, тогда можно будет сохранить файл
            if save == 1
                % Запрос на выбор папки
                folder_name = uigetdir;
                % Сохранение изображения в выбранной папке
                if folder_name ~= 0 % Если папка была выбрана
                    [~, baseFileName, ext] = fileparts('example.jpg');
                    fullFileName = fullfile(folder_name, [baseFileName, ext]);
                    imwrite(edgeImg, fullFileName); % Сохранение изображения
                    app.Label_3.Text = (['Изображение сохранено в папке: ', fullFileName]);
                else
                    % Иначе папка не выбрана и возможность сохранить будет недоступна 
                    app.Label_3.Text = ('Сохранение отменено');
                end
            else
                % Если save равен другому значению (нулю), сохранение будет невозможно
                app.Label_3.Text = ('Для сохранения сначала нужно выделить границы');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InputParametersUIFigure = uifigure;
            app.InputParametersUIFigure.Name = 'InputParameters';
            app.Button = uibutton(app.InputParametersUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Выделить границы';
            app.Label = uilabel(app.InputParametersUIFigure);
            app.Label.Text = 'Степень размытия (от 0.1 до 5)';
            app.EditField = uieditfield(app.InputParametersUIFigure, 'numeric');
            app.EditField.Limits = [0.1 5];
            app.EditField.Value = 1;
            app.Label_2 = uilabel(app.InputParametersUIFigure);
            app.Label_2.Text = 'Порог выделения границ (от 0.1 до 10)';
            app.EditField_2 = uieditfield(app.InputParametersUIFigure, 'numeric');
            app.EditField_2.Limits = [0.1 10];
            app.EditField_2.Value = 1;
            app.Button_2 = uibutton(app.InputParametersUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = 'Выбрать изображение';
            app.Label_3 = uilabel(app.InputParametersUIFigure);
            app.Label_3.Text = 'Выберите изображение';
            app.Button_3 = uibutton(app.InputParametersUIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = 'Сохранить';
            app.Label_4 = uilabel(app.InputParametersUIFigure);
            app.Label_4.Text = 'Подавление шумов и немаксимумов (от 0 до 10)';
            app.EditField_3 = uieditfield(app.InputParametersUIFigure, 'numeric');
            app.EditField_3.Limits = [0 10];
            app.EditField_3.Value = 1;
            app.Label_5 = uilabel(app.InputParametersUIFigure);
            app.Label_5.Text = 'Толщина границ (от 0 до 5)';
            app.EditField_4 = uieditfield(app.InputParametersUIFigure, 'numeric');
            app.EditField_4.Limits = [0 5];
            app.EditField_4.RoundFractionalValues = 'on';
            app.EditField_4.ValueDisplayFormat = '%.0f';
            app.EditField_4.Value = 1;
            app.Label_6 = uilabel(app.InputParametersUIFigure);
            app.Label_6.Text = 'Оператор:';
            app.DropDown = uidropdown(app.InputParametersUIFigure);
            app.DropDown.Items = {'Собеля', 'Робертса', 'Превитта', 'Шарра', 'Кирша'};
            app.DropDown.Value = 'Собеля';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InputParametersUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InputParametersUIFigure)
        end
    end
end